classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CreatedbyWongWeiFongTP065467IanGanJianHaoTP064373Label  matlab.ui.control.Label
        RecognisedCarPlateEditField   matlab.ui.control.EditField
        VehicleStateEditField         matlab.ui.control.EditField
        VehicleStateEditFieldLabel    matlab.ui.control.Label
        FirstCharacterEditField       matlab.ui.control.EditField
        FirstCharacterEditFieldLabel  matlab.ui.control.Label
        RecognisedCarPlateEditFieldLabel  matlab.ui.control.Label
        LicensePlateRecognitionLPRandStateIdentificationSystemSISLabel  matlab.ui.control.Label
        CategoryButtonGroup           matlab.ui.container.ButtonGroup
        BoatButton                    matlab.ui.control.ToggleButton
        BusButton                     matlab.ui.control.ToggleButton
        CarButton                     matlab.ui.control.ToggleButton
        LorryButton                   matlab.ui.control.ToggleButton
        SampleDropDown                matlab.ui.control.DropDown
        AnalyzeButton                 matlab.ui.control.Button
        SampleDropDownLabel           matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        CurrentCategory % Description
    end
    
    methods (Access = private)
        
        function analyzeImage(app, imagePath)
            clc;
            close all;

            % Step 1: Load and display the original image
            originalImg = imread(imagePath);
            
            % Prepare a single figure window
            figure('Name','License Plate Detection Steps','NumberTitle','off');
            
            subplot(3,3,1), imshow(originalImg), title('Original Image');
            
            % Step 2: Convert to grayscale
            grayImg = rgb2gray(originalImg);
            subplot(3,3,2), imshow(grayImg), title('Grayscale Image');
            
            % Step 3: Enhance contrast
            grayImg = imadjust(grayImg);
            subplot(3,3,3), imshow(grayImg), title('Contrast Enhanced');
            
            % Step 4: Edge detection using Canny
            edges = edge(grayImg, 'Canny', [0.1 0.3]);
            subplot(3,3,4), imshow(edges), title('Canny Edges');
            
            % Step 5: Morphological closing
            se = strel('rectangle', [3, 15]);
            closedImg = imclose(edges, se);
            subplot(3,3,5), imshow(closedImg), title('Morphological Closing');
            
            % Step 6: Use regionprops to find candidate boxes
            props = regionprops(closedImg, 'BoundingBox', 'Area', 'Extent');
            candidateBoxes = [];
            
            for k = 1:length(props)
                bbox = props(k).BoundingBox;
                area = props(k).Area;
                aspectRatio = bbox(3) / bbox(4);
                extent = props(k).Extent;
                
                if area > 200 && aspectRatio > 2 && aspectRatio < 6 && extent > 0.4
                    candidateBoxes = [candidateBoxes; bbox];
                end
            end
            
            if isempty(candidateBoxes)
                disp('No candidate plate regions detected.');
                return;
            end
            
            % Step 7: Select largest candidate region
            maxArea = 0;
            plateBoundingBox = [];
            for k = 1:size(candidateBoxes, 1)
                bbox = candidateBoxes(k,:);
                currentArea = bbox(3) * bbox(4);
                if currentArea > maxArea
                    maxArea = currentArea;
                    plateBoundingBox = bbox;
                end
            end
            
            % Step 8: Show detected box on original image
            detectedImg = insertShape(originalImg, 'Rectangle', plateBoundingBox, 'Color', 'red', 'LineWidth', 2);
            subplot(3,3,6), imshow(detectedImg), title('Detected Plate Box');
            
            % Step 9: Crop the plate
            plateImg = imcrop(grayImg, plateBoundingBox);
            subplot(3,3,7), imshow(plateImg), title('Cropped Plate');
            
            % Step 10: Enhance plate for OCR
            plateBW = imbinarize(plateImg, 'adaptive', 'Sensitivity', 0.45);
            %plateBW = imbinarize(plateImg);
            plateBW = imcomplement(plateBW);
            plateBW = medfilt2(plateBW, [2, 2]);
            subplot(3,3,8), imshow(plateBW), title('Enhanced for OCR');
            
            % Step 10.1: Segment characters using regionprops
            CC = bwconncomp(plateBW);
            stats = regionprops(CC, 'BoundingBox', 'Area');
            bboxes = cat(1, stats.BoundingBox);
            
            % Sort bounding boxes by x (horizontal) position
            [~, idx] = sort(bboxes(:,1));
            sortedBoxes = bboxes(idx, :);
            
            % Filter out boxes that are too small (noise)
            filteredBoxes = [];
            for i = 1:size(sortedBoxes,1)
                w = sortedBoxes(i,3);
                h = sortedBoxes(i,4);
                if w > 10 && h > 20 && w < 80 && h < 100  % reasonable width/height for chars
                    filteredBoxes = [filteredBoxes; sortedBoxes(i,:)];
                end
            end
            
            firstCharBySegmentation = '';
            if ~isempty(filteredBoxes)
                firstCharBox = filteredBoxes(1, :);  % First box from left
                firstCharImg = imcrop(plateBW, firstCharBox);
                firstCharImg = imresize(firstCharImg, [50 50]);  % Normalize size
                firstCharImg = imcomplement(firstCharImg);  % Black text on white background
            
                if ~islogical(firstCharImg)
                    firstCharImg = imbinarize(firstCharImg);
                end
                
                firstCharResult = ocr(firstCharImg, ...
                    'CharacterSet', 'ABCDFJKMNPRTVWZ', 'TextLayout', 'Word');
                firstCharCleaned = upper(regexprep(firstCharResult.Text, '[^A-Z]', ''));
                
                if ~isempty(firstCharCleaned)
                    firstCharBySegmentation = firstCharCleaned(1);
                end
                %class(firstCharImg)
            
            end
            
            % === ALTERNATIVE: Region-based detection for first char (left plate side) ===
            firstCharByRegion = '';
            [H, W] = size(plateBW);
            leftQuarter = imcrop(plateBW, [1, 1, round(W/4), H]);
            leftQuarter = imcomplement(leftQuarter);  % Invert image
            
            % Check if 'leftQuarter' is binary. If not, apply imbinarize.
            if ~islogical(leftQuarter)
                leftQuarter = imbinarize(leftQuarter);  % Binarize if not already binary
            end
            
            leftQuarter = imresize(leftQuarter, [50 50]);
            
            regionOCR = ocr(leftQuarter, ...
                'CharacterSet', 'ABCDFJKMNPRTVWZ', 'TextLayout', 'Word');
            cleanRegionText = upper(regexprep(regionOCR.Text, '[^A-Z]', ''));
            
            if ~isempty(cleanRegionText)
                firstCharByRegion = cleanRegionText(1);
            end
            
            
            % Step 11: OCR for the full plate
            results = ocr(plateBW, ...
                'CharacterSet', '0123456789ABCDEFGHIJKLMNOPQRSTUVWXYZ', 'TextLayout', 'Block');
            recognizedText = upper(regexprep(results.Text, '[\s]', ''));
            disp(['Recognized Plate Text: ', recognizedText]);
            
            % Step 12: Detect Malaysian state
            stateMap = containers.Map(...
                {'A','B','C','D','F','J','K','L','M','N','P','Q','R','S','T','V','W','Z'}, ...
                {'Perak','Selangor','Pahang','Kelantan','Putrajaya','Johor','Kedah','Labuan','Melaka','Negeri Sembilan','Penang','Sarawak','Perlis','Sabah','Terengganu','Kuala Lumpur','Kuala Lumpur','Military'});
            
            % Prioritize by: Segmented > Region > Full Plate
            if ~isempty(firstCharBySegmentation)
                firstChar = firstCharBySegmentation;
            elseif ~isempty(firstCharByRegion)
                firstChar = firstCharByRegion;
            elseif ~isempty(recognizedText)
                firstChar = recognizedText(1);
            else
                firstChar = '';
            end
            
            if ~isempty(firstChar) && isKey(stateMap, firstChar)
                state = stateMap(firstChar);
            else
                state = 'Unknown';
            end
            
            % Show debug info
            % disp(['First Char by Segmentation: ', firstCharBySegmentation]);
            %disp(['First Char by Region: ', firstCharByRegion]);
            disp(['Final First Character Used: ', firstChar]);
            disp(['Detected State: ', state]);

            app.RecognisedCarPlateEditField.Value = recognizedText;
            app.FirstCharacterEditField.Value = firstChar;
            app.VehicleStateEditField.Value = state;
            
            % Step 13: Display final result
            subplot(3,3,9), imshow(plateBW), ...
                title({['OCR: ', recognizedText], ...
                ['RegionChar: ', firstChar], ...
                ['State: ', state]});
                    end
                end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Image.ScaleMethod = 'fit';
            app.CurrentCategory = app.CategoryButtonGroup.SelectedObject.Text;
            CategoryButtonGroupSelectionChanged(app, []);  % Simulate selection to load images
        end

        % Callback function: AnalyzeButton, FirstCharacterEditField, 
        % ...and 2 other components
        function AnalyzeButtonPushed(app, event)
            selectedFile = app.SampleDropDown.Value;
            selectedCategory = app.CurrentCategory;
        
            %baseImagePath = 'C:\IPPR\matlab\Preprocessing';  % Adjust as needed
            baseImagePath = fullfile(fileparts(mfilename('fullpath')), 'Preprocessing');
            imagePath = fullfile(baseImagePath, selectedCategory, selectedFile);
        
            if isfile(imagePath)
                analyzeImage(app, imagePath);
            else
                uialert(app.UIFigure, 'Selected image not found.', 'Error');
            end
        end

        % Value changed function: SampleDropDown
        function SampleDropDownValueChanged(app, event)
            selectedFile = app.SampleDropDown.Value;
            selectedCategory = app.CurrentCategory;
        
            %baseImagePath = 'C:\IPPR\matlab\Preprocessing';  % Match your folder path
            baseImagePath = fullfile(fileparts(mfilename('fullpath')), 'Preprocessing');
            imagePath = fullfile(baseImagePath, selectedCategory, selectedFile);
        
            if isfile(imagePath)
                img = imread(imagePath);    
                app.Image.ImageSource = img;  % Shows image in uiimage
            else
                uialert(app.UIFigure, 'Image file not found.', 'Error');
            end
        end

        % Selection changed function: CategoryButtonGroup
        function CategoryButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.CategoryButtonGroup.SelectedObject.Text;
            app.CurrentCategory = selectedButton;
            
            %baseImagePath = 'C:\IPPR\matlab\Preprocessing';  % example
            baseImagePath = fullfile(fileparts(mfilename('fullpath')), 'Preprocessing');
            categoryFolder = fullfile(baseImagePath, selectedButton);
            
            disp(['Looking in: ', categoryFolder]);  % Debug print


            if isfolder(categoryFolder)
                imageFiles =    [dir(fullfile(categoryFolder, '*.jpg')); ...
                                dir(fullfile(categoryFolder, '*.png'))];
                fileNames = {imageFiles.name};  % Extract file names
        
                if ~isempty(fileNames)
                    app.SampleDropDown.Items = fileNames;
                    app.SampleDropDown.Value = fileNames{1};  % Select first item by default
                    SampleDropDownValueChanged(app, []);

                    % Auto-preview first image
                    imagePath = fullfile(categoryFolder, fileNames{1});
                    img = imread(imagePath);
                    app.Image.ImageSource = img;
                else
                    app.SampleDropDown.Items = {'<No images found>'};
                    app.Image.ImageSource = [];  % Clear image
                end
            else
                app.SampleDropDown.Items = {'<Invalid folder>'};
                app.Image.ImageSource = [];  % Clear image
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Boat', 'boat-AWR7120.jpg');
            app.SampleDropDownLabel = uilabel(app.UIFigure);
            app.SampleDropDownLabel.Text = 'Sample';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.SampleDropDown = uidropdown(app.UIFigure);
            app.SampleDropDown.Items = {'boat-TKS00114P.jpg'};
            app.SampleDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleDropDownValueChanged, true);
            app.SampleDropDown.Value = 'boat-TKS00114P.jpg';
            app.CategoryButtonGroup = uibuttongroup(app.UIFigure);
            app.CategoryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CategoryButtonGroupSelectionChanged, true);
            app.CategoryButtonGroup.Title = 'Category';
            app.LorryButton = uitogglebutton(app.CategoryButtonGroup);
            app.LorryButton.Text = 'Lorry';
            app.CarButton = uitogglebutton(app.CategoryButtonGroup);
            app.CarButton.Text = 'Car';
            app.BusButton = uitogglebutton(app.CategoryButtonGroup);
            app.BusButton.Text = 'Bus';
            app.BoatButton = uitogglebutton(app.CategoryButtonGroup);
            app.BoatButton.Text = 'Boat';
            app.BoatButton.Value = true;
            app.LicensePlateRecognitionLPRandStateIdentificationSystemSISLabel = uilabel(app.UIFigure);
            app.LicensePlateRecognitionLPRandStateIdentificationSystemSISLabel.Text = 'License Plate Recognition (LPR) and State Identification System (SIS)';
            app.RecognisedCarPlateEditFieldLabel = uilabel(app.UIFigure);
            app.RecognisedCarPlateEditFieldLabel.Text = 'Recognised Car Plate';
            app.FirstCharacterEditFieldLabel = uilabel(app.UIFigure);
            app.FirstCharacterEditFieldLabel.Text = 'First Character';
            app.FirstCharacterEditField = uieditfield(app.UIFigure, 'text');
            app.FirstCharacterEditField.ValueChangedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.FirstCharacterEditField.Editable = 'off';
            app.FirstCharacterEditField.Enable = 'off';
            app.VehicleStateEditFieldLabel = uilabel(app.UIFigure);
            app.VehicleStateEditFieldLabel.Text = 'Vehicle State';
            app.VehicleStateEditField = uieditfield(app.UIFigure, 'text');
            app.VehicleStateEditField.ValueChangedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.VehicleStateEditField.Editable = 'off';
            app.VehicleStateEditField.Enable = 'off';
            app.RecognisedCarPlateEditField = uieditfield(app.UIFigure, 'text');
            app.RecognisedCarPlateEditField.ValueChangedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.RecognisedCarPlateEditField.Editable = 'off';
            app.RecognisedCarPlateEditField.Enable = 'off';
            app.CreatedbyWongWeiFongTP065467IanGanJianHaoTP064373Label = uilabel(app.UIFigure);
            app.CreatedbyWongWeiFongTP065467IanGanJianHaoTP064373Label.Text = 'Created by Wong Wei Fong (TP065467) & Ian Gan Jian Hao (TP064373)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end